classdef LogAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        File                        matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        TofilexlsMenu               matlab.ui.container.Menu
        TofiletxtMenu               matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        ToolsMenu                   matlab.ui.container.Menu
        ZoomMenu                    matlab.ui.container.Menu
        AutoscaleMenu               matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        Tracking3DTab               matlab.ui.container.Tab
        Plot3D                      matlab.ui.control.UIAxes
        Tracking2DTab               matlab.ui.container.Tab
        Plot2D                      matlab.ui.control.UIAxes
        ReliefTab                   matlab.ui.container.Tab
        PlotRelief                  matlab.ui.control.UIAxes
        TrackingCourseTab           matlab.ui.container.Tab
        PlotCourse                  matlab.ui.control.UIAxes
        BIMTab                      matlab.ui.container.Tab
        PlotBIM                     matlab.ui.control.UIAxes
        TimeLabel                   matlab.ui.control.Label
        TimeSlider                  matlab.ui.control.Slider
        DataontimePanel             matlab.ui.container.Panel
        LongitudedegEditFieldLabel  matlab.ui.control.Label
        LongitudedegEditField       matlab.ui.control.EditField
        LatitudedegEditFieldLabel   matlab.ui.control.Label
        LatitudedegEditField        matlab.ui.control.EditField
        AltitudemEditFieldLabel     matlab.ui.control.Label
        AltitudemEditField          matlab.ui.control.EditField
        ReliefmEditFieldLabel       matlab.ui.control.Label
        ReliefmEditField            matlab.ui.control.EditField
        CoursedegEditFieldLabel     matlab.ui.control.Label
        CoursedegEditField          matlab.ui.control.EditField
        BIMCmdLabel                 matlab.ui.control.Label
        BIMCmdEditField             matlab.ui.control.EditField
        LeftBIMLabel                matlab.ui.control.Label
        LeftBIMEditField            matlab.ui.control.EditField
        RightBIMLabel               matlab.ui.control.Label
        RightBIMEditField           matlab.ui.control.EditField
        TouchdownpointPanel         matlab.ui.container.Panel
        LatitudedegLabel            matlab.ui.control.Label
        AltitudemLabel              matlab.ui.control.Label
        TDLat                       matlab.ui.control.Label
        TDAlt                       matlab.ui.control.Label
        LongitudedegLabel           matlab.ui.control.Label
        TDLon                       matlab.ui.control.Label
        FinalpointPanel             matlab.ui.container.Panel
        FinalLatLabel               matlab.ui.control.Label
        FinalAltLabel               matlab.ui.control.Label
        FinalLatValue               matlab.ui.control.Label
        FinalAltValue               matlab.ui.control.Label
        FinalLonLabel               matlab.ui.control.Label
        FinalLonValue               matlab.ui.control.Label
        FilenamePanel               matlab.ui.container.Panel
        FileName                    matlab.ui.control.EditField
    end


    properties (Access = private)
        file = -1;
        path;
        filename;
        TagList = {
            'Timestamp, sec: ';
            'Model_Lat, deg: ';
            'Model_Lon, deg: ';
            'Model_Alt, m: ';
            'Model_Course, rad: ';
            'MAP, m: ';
            'Model_BIM_CMD: ';
            'BIML_Pos: ';
            'BIMR_Pos: ';
            'TD_Lat: ';
            'TD_Lon: ';
            'TD_Alt: ';
            'Fin_Lat, deg: ';
            'Fin_Lon, deg: ';
            'Fin_Alt, m: ';
            'Fin_SWSHeight: ';
            'Fin_MAP, m: ';
        };
        ParseResult   = [];
        Timestamp     = [];
        Model_Lat     = [];
        Model_Lon     = [];
        Model_Alt     = [];
        Model_Course  = [];
        MAP           = [];
        Model_BIM_CMD = [];
        BIML_Pos      = [];
        BIMR_Pos      = [];
        Model_TDLat   = [];
        Model_TDLon   = [];
        Model_TDAlt   = [];
        Model_FinLat  = [];
        Model_FinLon  = [];
        Model_FinAlt  = [];
        Fin_SWSHeight = [];
        Fin_MAP       = [];
        
        % Перемещаемые точки на графиках
        PointOn3D;
        PointOn2D;
        PointOnRelief;
        PointOnCourse;
        PointOnBIM;
        
        % Флаг загружены ли данные?
        status = 0; 
    end

    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   PlotCreator - Построитель графиков 
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function PlotCreator (app)
            % Очистим все графики
            cla(app.Plot2D,'reset');
            cla(app.Plot3D,'reset');
            cla(app.PlotRelief,'reset');
            cla(app.PlotCourse,'reset');
            cla(app.PlotBIM,'reset');
            % Строим плоскую траекторию
            plot(app.Plot2D, app.Model_Lon, app.Model_Lat);
            title(app.Plot2D, "2D Traectory");
            xlabel(app.Plot2D,'Longitude, deg');
            ylabel(app.Plot2D,'Latitude, deg');
            grid(app.Plot2D,'on');
            zoom(app.Plot2D,'on');
            hold(app.Plot2D,'on')
            % Строим трехмерную траекторию
            plot3(app.Plot3D, app.Model_Lon, app.Model_Lat, app.Model_Alt);
            title(app.Plot3D, "3D Traectory");
            xlabel(app.Plot3D,'Longitude, deg');
            ylabel(app.Plot3D,'Latitude, deg');
            zlabel(app.Plot3D,'Altitude, m');
            rotate3d(app.Plot3D, 'on'); 
            grid(app.Plot3D,'on');
            % Строим график рельефа
            plot(app.PlotRelief, app.Timestamp, app.MAP);
            title(app.PlotRelief, "Relief");
            xlabel(app.PlotRelief,'Time, sec');
            ylabel(app.PlotRelief,'Height, m');
            grid(app.PlotRelief,'on');
            zoom(app.PlotRelief,'on');
            xlim(app.PlotRelief, [app.Timestamp(1) app.Timestamp(end)]);
            ylim(app.PlotRelief, [0 inf]);
            % Строим график изменения курса
            plot(app.PlotCourse, app.Timestamp, app.Model_Course/pi*180);
            title(app.PlotCourse, "Course");
            xlabel(app.PlotCourse,'Time, sec');
            ylabel(app.PlotCourse,'Course, deg');
            grid(app.PlotCourse,'on');
            zoom(app.PlotCourse,'on');
            xlim(app.PlotCourse, [app.Timestamp(1) app.Timestamp(end)]);
            % Строим график БИМ
            plot(app.PlotBIM, app.Timestamp, app.Model_BIM_CMD);
            title(app.PlotBIM, "BIM state");
            xlabel(app.PlotBIM,'Time, sec');
            ylabel(app.PlotBIM,'BIM state, %');
            grid(app.PlotBIM,'on');
            zoom(app.PlotBIM,'on');
            hold(app.PlotBIM,'on')
            plot(app.PlotBIM, app.Timestamp, app.BIMR_Pos);
            hold(app.PlotBIM,'on')
            plot(app.PlotBIM, app.Timestamp, -app.BIML_Pos);
            legend(app.PlotBIM,'Command','Right', 'Left' , 'OnTime');
            xlim(app.PlotBIM, [app.Timestamp(1) app.Timestamp(end)]);
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   PrintPoint - Отрисовать точку в момент времени time на всех графиках 
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function PrintPoint(app, time)
            % Удаляем объекты точек 
            delete(app.PointOn3D);
            delete(app.PointOn2D);
            delete(app.PointOnRelief);
            delete(app.PointOnCourse);
            delete(app.PointOnBIM);
            % Разрешаем добавление точек на графики
            hold(app.Plot3D,'on');
            hold(app.Plot2D,'on')
            hold(app.PlotRelief,'on');
            hold(app.PlotCourse,'on');
            hold(app.PlotBIM,'on');
            % Добавляем точки на графики 
            app.PointOn3D = plot3(app.Plot3D, app.Model_Lon(time + 1), app.Model_Lat(time + 1), app.Model_Alt(time + 1), 'ko');
            app.PointOn2D = plot(app.Plot2D, app.Model_Lon(time + 1), app.Model_Lat(time + 1), 'ko');
            app.PointOnRelief = plot(app.PlotRelief, time, app.MAP(time + 1), 'ko');
            app.PointOnCourse = plot(app.PlotCourse, time, app.Model_Course(time + 1)/pi*180, 'ko');
            app.PointOnBIM = plot(app.PlotBIM, time, app.Model_BIM_CMD(time + 1), 'ko');
                legend(app.PlotBIM,'Command','Right', 'Left' , 'OnTime');
            % Блокируем добавление точек на графики
            hold(app.Plot3D,'off');
            hold(app.Plot2D,'off')
            hold(app.PlotRelief,'off');
            hold(app.PlotCourse,'off');
            hold(app.PlotBIM,'off');
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   DataOnTimePrint - Вывод данных момента времени time в специальные блоки 
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function DataOnTimePrint(app, time)
            app.DataontimePanel.Title       =  "Data on time: " + num2str(time) + "sec";
            app.LongitudedegEditField.Value = num2str(app.Model_Lon(time + 1));
            app.LatitudedegEditField.Value  = num2str(app.Model_Lat(time + 1));
            app.AltitudemEditField.Value    = num2str(app.Model_Alt(time + 1));
            app.ReliefmEditField.Value      = num2str(app.MAP(time + 1));
            app.CoursedegEditField.Value    = num2str(app.Model_Course(time + 1)/pi*180);
            app.BIMCmdEditField.Value       = num2str(app.Model_BIM_CMD(time + 1));
            app.LeftBIMEditField.Value      = num2str( - app.BIML_Pos(time + 1));
            app.RightBIMEditField.Value     = num2str(app.BIMR_Pos(time + 1));
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   ZoomReset - Возврат к оригинальному виду графиков 
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function ZoomReset(app)
            app.PlotCreator ();
            time = fix(app.TimeSlider.Value);
            PrintPoint(app, time);
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   OpenFileNLoadData - Открыть и распарсить лог файл 
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function OpenFileNLoadData(app)
            % Запускаем окно выбора файла и получим его путь и имя
             [app.filename,app.path] = uigetfile('* .log');
             
             % Если файл был выбран
             if app.filename ~= 0
                 % Открываем файл       
                 fullFileName = strcat(app.path, app.filename);
                 app.file = fopen(fullFileName, 'r');
                 if app.file == -1
                     error('File is not opened');
                 else
                    % Печатаем имя в поле
                    app.FileName.Value = app.filename;
                    % Парсим файл
                    app.ParseResult = ParseFileByTags (app.file, app.TagList);
                    % Пропускаем  результат парсинга через валидатор/восстановитель
                    app.ParseResult = RepairCellData(app.ParseResult);            
                    % Раскидываем результат парсинга по своим массивам
                    app.Timestamp     = app.ParseResult{1};
                    app.Model_Lat     = app.ParseResult{2};
                    app.Model_Lon     = app.ParseResult{3};
                    app.Model_Alt     = app.ParseResult{4};
                    app.Model_Course  = app.ParseResult{5};
                    app.MAP           = app.ParseResult{6};
                    app.Model_BIM_CMD = app.ParseResult{7};
                    app.BIML_Pos      = app.ParseResult{8};
                    app.BIMR_Pos      = app.ParseResult{9};
                    app.Model_TDLat   = app.ParseResult{10};
                    app.Model_TDLon   = app.ParseResult{11};
                    app.Model_TDAlt   = app.ParseResult{12};
                    app.Model_FinLat  = app.ParseResult{13};
                    app.Model_FinLon  = app.ParseResult{14};
                    app.Model_FinAlt  = app.ParseResult{15};
                    app.Fin_SWSHeight = app.ParseResult{16};
                    app.Fin_MAP       = app.ParseResult{17};

                    % Очищаем 
                    app.ParseResult = [];
                    % Строим графики
                    app.PlotCreator();
                    
                    % Настраиваем слайдер времени
                    app.TimeSlider.Value = 0;
                    app.TimeSlider.Limits = [app.Timestamp(1) app.Timestamp(end)];
                    app.TimeSlider.Enable = 'on';
                    % Размещаем точку движения в начало траекторий
                    PrintPoint(app, fix(0));
                    DataOnTimePrint(app, fix(0));
                    
                    % Разблокируем кнопку сброса масштабирования
                    app.status = 1;
                    
                    % Выведем информацию о точке приземления
                    TDInfoPrint(app, app.Model_TDLat(1), app.Model_TDLon(1), app.Model_TDAlt(1));
                    % Выведем информацию о фактической точке приземления
                    FinishPointInfoPrint(app, app.Model_FinLat(1), app.Model_FinLon(1), app.Model_FinAlt(1));
                    
                 end
                 fclose(app.file);
             end
             app.file = -1;
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   ClearApp - Очистить приложение
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function ClearApp(app)
            % Очистим все графики
            cla(app.Plot2D,'reset');
            cla(app.Plot3D,'reset');
            cla(app.PlotRelief,'reset');
            cla(app.PlotCourse,'reset');
            cla(app.PlotBIM,'reset');
            % Но подписи нужно вернуть 
            title(app.Plot2D, "2D Traectory");
            xlabel(app.Plot2D,'Longitude, deg');
            ylabel(app.Plot2D,'Latitude, deg');
            % Но подписи нужно вернуть
            title(app.Plot3D, "3D Traectory");
            xlabel(app.Plot3D,'Longitude, deg');
            ylabel(app.Plot3D,'Latitude, deg');
            zlabel(app.Plot3D,'Altitude, m');
            % Но подписи нужно вернуть
            title(app.PlotRelief, "Relief");
            xlabel(app.PlotRelief,'Time, sec');
            ylabel(app.PlotRelief,'Height, m');
            % Но подписи нужно вернуть
            title(app.PlotCourse, "Course");
            xlabel(app.PlotCourse,'Time, sec');
            ylabel(app.PlotCourse,'Course, deg');
            % Но подписи нужно вернуть
            plot(app.PlotBIM, app.Timestamp, app.Model_BIM_CMD);
            title(app.PlotBIM, "BIM state");
            xlabel(app.PlotBIM,'Time, sec');
            ylabel(app.PlotBIM,'BIM state, %');
            % Сбрасываем флаг, что данные загружены
            app.status = 0;
            % Очищаем наши массивы данных
            app.ParseResult   = [];
            app.Timestamp     = [];
            app.Model_Lat     = [];
            app.Model_Lon     = [];
            app.Model_Alt     = [];
            app.Model_Course  = [];
            app.MAP           = [];
            app.Model_BIM_CMD = [];
            app.BIML_Pos      = [];
            app.BIMR_Pos      = [];
            app.Model_TDLat   = [];
            app.Model_TDLon   = [];
            app.Model_TDAlt   = [];
            app.Model_FinLat  = [];
            app.Model_FinLon  = [];
            app.Model_FinAlt  = [];
            app.Fin_SWSHeight = [];
            app.Fin_MAP       = [];
            % Очищаем все текстбоксы
            app.LongitudedegEditField.Value = '';
            app.LatitudedegEditField.Value = '';
            app.AltitudemEditField.Value = '';
            app.ReliefmEditField.Value = '';
            app.CoursedegEditField.Value = '';
            app.BIMCmdEditField.Value = '';
            app.LeftBIMEditField.Value = '';
            app.RightBIMEditField.Value = '';
            app.FileName.Value = '';
            
            % Выведем информацию о точке приземления
            TDInfoPrint(app, 0, 0, 0);
            % Выведем информацию о фактической точке приземления
            FinishPointInfoPrint(app, 0, 0, 0);
            % Заблокируем слайдер
            app.TimeSlider.Enable = 'off';
            
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   TDInfoPrint - Вывод инфомации о точке приземления
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function TDInfoPrint(app, lat, lon, alt)
            app.TDLat.Text = num2str(lat);
            app.TDLon.Text = num2str(lon);
            app.TDAlt.Text = num2str(alt);
        end
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        %   TDInfoPrint - Вывод инфомации о точке приземления
        %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function FinishPointInfoPrint(app, lat, lon, alt)
            app.FinalLatValue.Text = num2str(lat);
            app.FinalLonValue.Text = num2str(lon);
            app.FinalAltValue.Text = num2str(alt);
        end
        
    end


    methods (Access = private)

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            time = fix(event.Value);
            PrintPoint(app, time);
            DataOnTimePrint(app, time);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            OpenFileNLoadData(app);
        end

        % Menu selected function: AutoscaleMenu
        function AutoscaleMenuSelected(app, event)
            if app.status ~= 0
                ZoomReset(app);
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            ClearApp(app);
        end

        % Menu selected function: TofilexlsMenu
        function TofilexlsMenuSelected(app, event)
            if app.status ~= 0
                [filenamexls, pathxls] = uiputfile('* .xls');
                fullFileName = strcat(pathxls, filenamexls);
                
                % Растянем скаляры до размера остальных матриц, что воспользоваться
                % функциями работы с таблицами
                fullSize = size(app.Timestamp);
                
                TDP = zeros( fullSize);
                TDP(1) =  app.Model_TDLat;
                TDP(2) = app.Model_TDLon;
                TDP(3) = app.Model_TDAlt;
                
                FinPoint = zeros(fullSize);
                FinPoint(1) = app.Model_FinLat;
                FinPoint(2) = app.Model_FinLon;
                FinPoint(3) = app.Model_FinAlt;
                FinPoint(4) = app.Fin_SWSHeight;
                FinPoint(5) = app.Fin_MAP;
                
                
                T = table(app.Timestamp, app.Model_Lat, app.Model_Lon,...
                app.Model_Alt, app.Model_Course, app.MAP, app.Model_BIM_CMD,...
                app.BIML_Pos, app.BIMR_Pos, TDP, FinPoint, ...
                'VariableNames',{'Time_sec','Latitude_deg', ...
                'Longitude_deg', 'Altitude_m', 'Course_rad',...
                'Relief_m', 'CommandBIM_per', 'LeftBIM_per',...
                'RightBIM_per', 'TouchdownPoint' , 'FinalPoint'});
                
                writetable(T,fullFileName);
            else
                errordlg('File is not open', 'Export to txt-file');
            end
        end

        % Menu selected function: TofiletxtMenu
        function TofiletxtMenuSelected(app, event)
            if app.status ~= 0
                [filenamexls, pathxls] = uiputfile('* .txt');
                fullFileName = strcat(pathxls, filenamexls);
                
                % Растянем скаляры до размера остальных матриц, что воспользоваться
                % функциями работы с таблицами
                fullSize = size(app.Timestamp);
                
                TDP = zeros( fullSize);
                TDP(1) =  app.Model_TDLat;
                TDP(2) = app.Model_TDLon;
                TDP(3) = app.Model_TDAlt;
                
                FinPoint = zeros(fullSize);
                FinPoint(1) = app.Model_FinLat;
                FinPoint(2) = app.Model_FinLon;
                FinPoint(3) = app.Model_FinAlt;
                FinPoint(4) = app.Fin_SWSHeight;
                FinPoint(5) = app.Fin_MAP;
                
                
                T = table(app.Timestamp, app.Model_Lat, app.Model_Lon,...
                app.Model_Alt, app.Model_Course, app.MAP, app.Model_BIM_CMD,...
                app.BIML_Pos, app.BIMR_Pos, TDP, FinPoint, ...
                'VariableNames',{'Time_sec','Latitude_deg', ...
                'Longitude_deg', 'Altitude_m', 'Course_rad',...
                'Relief_m', 'CommandBIM_per', 'LeftBIM_per',...
                'RightBIM_per', 'TouchdownPoint' , 'FinalPoint'});
                
                writetable(T,fullFileName);
            else
                errordlg('File is not open', 'Export to xls-file');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.File = uifigure;
            app.File.Name = 'Log analyzer';
            app.FileMenu = uimenu(app.File);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.TofilexlsMenu = uimenu(app.ExportMenu);
            app.TofilexlsMenu.MenuSelectedFcn = createCallbackFcn(app, @TofilexlsMenuSelected, true);
            app.TofilexlsMenu.Text = 'To file.xls';
            app.TofiletxtMenu = uimenu(app.ExportMenu);
            app.TofiletxtMenu.MenuSelectedFcn = createCallbackFcn(app, @TofiletxtMenuSelected, true);
            app.TofiletxtMenu.Text = 'To file.txt';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.ToolsMenu = uimenu(app.File);
            app.ToolsMenu.Text = 'Tools';
            app.ZoomMenu = uimenu(app.ToolsMenu);
            app.ZoomMenu.Text = 'Zoom ';
            app.AutoscaleMenu = uimenu(app.ZoomMenu);
            app.AutoscaleMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoscaleMenuSelected, true);
            app.AutoscaleMenu.Text = 'Autoscale';
            app.Panel = uipanel(app.File);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tracking3DTab = uitab(app.TabGroup);
            app.Tracking3DTab.Title = 'Tracking3D';
            app.Plot3D = uiaxes(app.Tracking3DTab);
            title(app.Plot3D, '3D Traectory')
            xlabel(app.Plot3D, 'Longitude, deg')
            ylabel(app.Plot3D, 'Latitude, deg')
            app.Tracking2DTab = uitab(app.TabGroup);
            app.Tracking2DTab.Title = 'Tracking2D ';
            app.Plot2D = uiaxes(app.Tracking2DTab);
            title(app.Plot2D, '2D Traectory')
            xlabel(app.Plot2D, 'Longitude, deg')
            ylabel(app.Plot2D, 'Latitude, deg')
            app.ReliefTab = uitab(app.TabGroup);
            app.ReliefTab.Title = 'Relief';
            app.PlotRelief = uiaxes(app.ReliefTab);
            title(app.PlotRelief, 'Relief')
            xlabel(app.PlotRelief, 'Time, sec')
            ylabel(app.PlotRelief, 'Height, m')
            app.TrackingCourseTab = uitab(app.TabGroup);
            app.TrackingCourseTab.Title = 'TrackingCourse';
            app.PlotCourse = uiaxes(app.TrackingCourseTab);
            title(app.PlotCourse, 'Course')
            xlabel(app.PlotCourse, 'Time, sec')
            ylabel(app.PlotCourse, 'Course, deg')
            app.BIMTab = uitab(app.TabGroup);
            app.BIMTab.Title = 'BIM';
            app.PlotBIM = uiaxes(app.BIMTab);
            title(app.PlotBIM, 'BIM state')
            xlabel(app.PlotBIM, 'Time, sec')
            ylabel(app.PlotBIM, 'BIM state, %')
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Panel);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Enable = 'off';
            app.DataontimePanel = uipanel(app.Panel);
            app.DataontimePanel.Title = 'Data on time';
            app.LongitudedegEditFieldLabel = uilabel(app.DataontimePanel);
            app.LongitudedegEditFieldLabel.Text = 'Longitude, deg';
            app.LongitudedegEditField = uieditfield(app.DataontimePanel, 'text');
            app.LongitudedegEditField.Editable = 'off';
            app.LatitudedegEditFieldLabel = uilabel(app.DataontimePanel);
            app.LatitudedegEditFieldLabel.Text = 'Latitude, deg';
            app.LatitudedegEditField = uieditfield(app.DataontimePanel, 'text');
            app.LatitudedegEditField.Editable = 'off';
            app.AltitudemEditFieldLabel = uilabel(app.DataontimePanel);
            app.AltitudemEditFieldLabel.Text = 'Altitude, m';
            app.AltitudemEditField = uieditfield(app.DataontimePanel, 'text');
            app.AltitudemEditField.Editable = 'off';
            app.ReliefmEditFieldLabel = uilabel(app.DataontimePanel);
            app.ReliefmEditFieldLabel.Text = 'Relief, m';
            app.ReliefmEditField = uieditfield(app.DataontimePanel, 'text');
            app.ReliefmEditField.Editable = 'off';
            app.CoursedegEditFieldLabel = uilabel(app.DataontimePanel);
            app.CoursedegEditFieldLabel.Text = 'Course, deg';
            app.CoursedegEditField = uieditfield(app.DataontimePanel, 'text');
            app.CoursedegEditField.Editable = 'off';
            app.BIMCmdLabel = uilabel(app.DataontimePanel);
            app.BIMCmdLabel.Text = 'BIM Cmd, %';
            app.BIMCmdEditField = uieditfield(app.DataontimePanel, 'text');
            app.BIMCmdEditField.Editable = 'off';
            app.LeftBIMLabel = uilabel(app.DataontimePanel);
            app.LeftBIMLabel.Text = 'Left BIM, %';
            app.LeftBIMEditField = uieditfield(app.DataontimePanel, 'text');
            app.LeftBIMEditField.Editable = 'off';
            app.RightBIMLabel = uilabel(app.DataontimePanel);
            app.RightBIMLabel.Text = 'Right BIM, %';
            app.RightBIMEditField = uieditfield(app.DataontimePanel, 'text');
            app.RightBIMEditField.Editable = 'off';
            app.TouchdownpointPanel = uipanel(app.File);
            app.TouchdownpointPanel.Title = 'Touchdown point';
            app.LatitudedegLabel = uilabel(app.TouchdownpointPanel);
            app.LatitudedegLabel.Text = 'Latitude, deg';
            app.AltitudemLabel = uilabel(app.TouchdownpointPanel);
            app.AltitudemLabel.Text = 'Altitude, m';
            app.TDLat = uilabel(app.TouchdownpointPanel);
            app.TDLat.Text = '0';
            app.TDAlt = uilabel(app.TouchdownpointPanel);
            app.TDAlt.Text = '0';
            app.LongitudedegLabel = uilabel(app.TouchdownpointPanel);
            app.LongitudedegLabel.Text = 'Longitude, deg';
            app.TDLon = uilabel(app.TouchdownpointPanel);
            app.TDLon.Text = '0';
            app.FinalpointPanel = uipanel(app.File);
            app.FinalpointPanel.Title = 'Final point';
            app.FinalLatLabel = uilabel(app.FinalpointPanel);
            app.FinalLatLabel.Text = 'Latitude, deg';
            app.FinalAltLabel = uilabel(app.FinalpointPanel);
            app.FinalAltLabel.Text = 'Altitude, m';
            app.FinalLatValue = uilabel(app.FinalpointPanel);
            app.FinalLatValue.Text = '0';
            app.FinalAltValue = uilabel(app.FinalpointPanel);
            app.FinalAltValue.Text = '0';
            app.FinalLonLabel = uilabel(app.FinalpointPanel);
            app.FinalLonLabel.Text = 'Longitude, deg';
            app.FinalLonValue = uilabel(app.FinalpointPanel);
            app.FinalLonValue.Text = '0';
            app.FilenamePanel = uipanel(app.File);
            app.FilenamePanel.Title = 'Filename';
            app.FileName = uieditfield(app.FilenamePanel, 'text');
            app.FileName.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LogAnalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.File)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.File)
        end
    end
end