classdef ControllerTuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BupRegulatorTunerUIFigure       matlab.ui.Figure
        touchdownPointPanel             matlab.ui.container.Panel
        LatitudedegLabel                matlab.ui.control.Label
        touchdownLatitudeEdit           matlab.ui.control.NumericEditField
        LongitudedegLabel               matlab.ui.control.Label
        touchdownLongitudeEdit          matlab.ui.control.NumericEditField
        AltitudemLabel                  matlab.ui.control.Label
        touchdownAltitudeEdit           matlab.ui.control.NumericEditField
        initialPointPanel               matlab.ui.container.Panel
        LatitudedegLabel_2              matlab.ui.control.Label
        initialPointLatitudeEdit        matlab.ui.control.NumericEditField
        LongitudedegLabel_2             matlab.ui.control.Label
        initialPointLongitudeEdit       matlab.ui.control.NumericEditField
        AltitudemLabel_2                matlab.ui.control.Label
        initialPointAltitudeEdit        matlab.ui.control.NumericEditField
        windControlPanel                matlab.ui.container.Panel
        WindAngleKnobLabel              matlab.ui.control.Label
        windAngleKnob                   matlab.ui.control.Knob
        SpeedSliderLabel                matlab.ui.control.Label
        windSpeedSlider                 matlab.ui.control.Slider
        autoWindCheckbox                matlab.ui.control.CheckBox
        regulatorSettingsPanel          matlab.ui.container.Panel
        StapLimitLabel                  matlab.ui.control.Label
        strapLimit                      matlab.ui.control.NumericEditField
        GainSensitivityLabel            matlab.ui.control.Label
        sensitivity                     matlab.ui.control.NumericEditField
        BoxSizemLabel                   matlab.ui.control.Label
        boxSize                         matlab.ui.control.NumericEditField
        AngleStepdegLabel               matlab.ui.control.Label
        angle                           matlab.ui.control.NumericEditField
        ArrivalRadiusmLabel             matlab.ui.control.Label
        arrivalRadius                   matlab.ui.control.NumericEditField
        runButton                       matlab.ui.control.Button
        tabs                            matlab.ui.container.TabGroup
        plot2dTab                       matlab.ui.container.Tab
        plot2d                          matlab.ui.control.UIAxes
        plot3dTab                       matlab.ui.container.Tab
        plot3d                          matlab.ui.control.UIAxes
        plantParametersPanel            matlab.ui.container.Panel
        HorspeedVhmsEditFieldLabel      matlab.ui.control.Label
        horizontalVelocityEdit          matlab.ui.control.NumericEditField
        VerspeedVvmsEditFieldLabel      matlab.ui.control.Label
        verticalVelocityEdit            matlab.ui.control.NumericEditField
        Turnspeedat50degsLabel          matlab.ui.control.Label
        turningSpeedEdit                matlab.ui.control.NumericEditField
        restoreSessionButton            matlab.ui.control.Button
        regulatorSwithPanel             matlab.ui.container.Panel
        TouchdownCommandSwitchLabel     matlab.ui.control.Label
        touchdownSystemEnable           matlab.ui.control.Switch
        TouchdownRegulatorSwitchLabel   matlab.ui.control.Label
        TD_Reg_Select                   matlab.ui.control.Switch
        InternalcoursecalculatorSwitchLabel  matlab.ui.control.Label
        internalCourseCalculatorEnable  matlab.ui.control.Switch
        regulatorModePanel              matlab.ui.container.ButtonGroup
        onlyTargetingModeButton         matlab.ui.control.RadioButton
        boxModeButton                   matlab.ui.control.RadioButton
        progressiveModeButton           matlab.ui.control.RadioButton
        reliefPanel                     matlab.ui.container.Panel
        autoReliefCheckbox              matlab.ui.control.CheckBox
        CurrentpointreliefmLabel        matlab.ui.control.Label
        currentPointReliefEdit          matlab.ui.control.NumericEditField
        TouchdownpointreliefmLabel      matlab.ui.control.Label
        touchdownPointReliefEdit        matlab.ui.control.NumericEditField
        currentReliefAvailableCheckbox  matlab.ui.control.CheckBox
        RadiopointreliefmEditFieldLabel  matlab.ui.control.Label
        radioPointReliefEdit            matlab.ui.control.NumericEditField
        radioPointPanel                 matlab.ui.container.Panel
        LatitudedegLabel_3              matlab.ui.control.Label
        radioPointLatitudeEdit          matlab.ui.control.NumericEditField
        LongitudedegLabel_3             matlab.ui.control.Label
        radioPointLongitudeEdit         matlab.ui.control.NumericEditField
        AltitudemLabel_3                matlab.ui.control.Label
        radioPointAltitudeEdit          matlab.ui.control.NumericEditField
        SendtimesecLabel                matlab.ui.control.Label
        sendtimeEdit                    matlab.ui.control.EditField
    end


    properties (Access = public)
        time
        vec_state
        output
    end

    properties (Access = private)
        X_TDP
        Y_TDP
    end

    methods (Access = private)
    
        function EnableDisableWindPanel(app, value)
            if value == 0
                app.windAngleKnob.Enable = 'on';
                app.windSpeedSlider.Enable = 'on';
                set_param('fullSystemModel/WindScenario/AutoModeA', 'sw',  '0');  
                set_param('fullSystemModel/WindScenario/AutoModeS', 'sw',  '0');   
            else
                app.windAngleKnob.Enable = 'off';
                app.windSpeedSlider.Enable = 'off';
                set_param('fullSystemModel/WindScenario/AutoModeA', 'sw',  '1');  
                set_param('fullSystemModel/WindScenario/AutoModeS', 'sw',  '1');  
            end
        end
        function EnableDisableReliefPanel(app, value)
            if value == 0
                app.currentPointReliefEdit.Enable  = 'on';
                app.currentReliefAvailableCheckbox.Enable  = 'on';
                app.touchdownPointReliefEdit.Enable = 'on';
                set_param('fullSystemModel/ReliefScenario/AutoModeA', 'sw',  '0');  
                set_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw',  '0');   
            else
                app.currentPointReliefEdit.Enable  = 'off';
                app.currentReliefAvailableCheckbox.Enable  = 'off';
                app.touchdownPointReliefEdit.Enable = 'off';
                set_param('fullSystemModel/ReliefScenario/AutoModeA', 'sw',  '1');  
                set_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw',  '1');  
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            % Очищаем графики от старых данных
            cla(app.plot2d, 'reset')
            cla(app.plot3d,'reset')
            
            % Устанавливаем координаты точки приземления
            set_param('fullSystemModel/TDP_lon_x', 'Value',  num2str(app.touchdownLongitudeEdit.Value, 10));
            set_param('fullSystemModel/TDP_lat_y', 'Value', num2str(app.touchdownLatitudeEdit.Value, 10));
            set_param('fullSystemModel/TDP_alt_z', 'Value', num2str(app.touchdownAltitudeEdit.Value, 10));
            
            % Устанавливаем координаты точки выброски
            set_param('fullSystemModel/Initial_LON', 'Value', num2str(app.initialPointLongitudeEdit.Value, 10));  
            set_param('fullSystemModel/Initial_LAT', 'Value', num2str(app.initialPointLatitudeEdit.Value, 10));
            set_param('fullSystemModel/Initial_ALT', 'Value', num2str(app.initialPointAltitudeEdit.Value, 10));
            
            % Устанавливаем точку приземления от радиостанции
            set_param('fullSystemModel/Radiostation/radioPoint.lat', 'Value', num2str(app.radioPointLatitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/radioPoint.lon', 'Value', num2str(app.radioPointLongitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/radioPoint.alt', 'Value', num2str(app.radioPointAltitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/sendTime', 'Time', num2str(app.sendtimeEdit.Value)); 
            
            % Настроим параметры объекта управления
            set_param('fullSystemModel/Plant/Vh1', 'Gain',  num2str(app.horizontalVelocityEdit.Value,10));
            set_param('fullSystemModel/Plant/Vh2', 'Gain',  num2str(app.horizontalVelocityEdit.Value,10));
            set_param('fullSystemModel/Plant/Vv', 'Gain',  num2str(app.verticalVelocityEdit.Value,10));
            set_param('fullSystemModel/Plant/AngleSpeed', 'Gain',  num2str(1/(50/app.turningSpeedEdit.Value),10));
            
            % Настроим параметры базового регулятора
            set_param('fullSystemModel/flightController/Targeting/Base_Sat', 'UpperLimit', num2str(app.strapLimit.Value,10)); 
            set_param('fullSystemModel/flightController/Targeting/Base_Sat', 'LowerLimit', num2str(-app.strapLimit.Value,10)); % со знаком минус 
            set_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain', num2str(app.sensitivity.Value,10));
            set_param('fullSystemModel/flightController/BoxSize', 'Value', num2str(app.boxSize.Value,10));
            set_param('fullSystemModel/flightController/AngleManevr', 'Value', num2str(app.angle.Value,10));
            set_param('fullSystemModel/flightController/ArrivalRadius', 'Value', num2str(app.arrivalRadius.Value,10));
            set_param('fullSystemModel/flightController/TurnSpeed', 'Value', num2str(app.turningSpeedEdit.Value,10)); 
                      
            % Зададаём параметры ветра
            set_param('fullSystemModel/WindScenario/WindSpeed', 'Value',  num2str(app.windSpeedSlider.Value));  
            set_param('fullSystemModel/WindScenario/WindAngle', 'Value',  num2str(app.windAngleKnob.Value));
           
            % Устанавливаем ключи
            set_param('fullSystemModel/flightController/TD_SysSwitch', 'sw', app.touchdownSystemEnable.Value);
            set_param('fullSystemModel/flightController/DataAnalyzer/CourseSwitch', 'sw', app.internalCourseCalculatorEnable.Value);
            %set_param('fullSystemModel/flightController/Mode','Value', app.TD_Reg_Select.Value);
            
            
            % Устанавливаем параметры имитатора рельефа
            set_param('fullSystemModel/ReliefScenario/ReliefValue', 'Value',           num2str(app.currentPointReliefEdit.Value));  
            set_param('fullSystemModel/ReliefScenario/ReliefAvailableValue', 'Value',  num2str(app.currentReliefAvailableCheckbox.Value));
            set_param('fullSystemModel/ReliefScenario/ReliefOnTDPValue', 'Value',      num2str(app.touchdownPointReliefEdit.Value));  
            set_param('fullSystemModel/ReliefScenario/ReliefOnRPValue', 'Value',       num2str(app.radioPointReliefEdit.Value));  
            
            % Устанавливаем режим регулятора
            if app.onlyTargetingModeButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '0');
            elseif app.progressiveModeButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '2');
            elseif app.boxModeButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '1');
            end

            [app.time, app.vec_state, app.output] = sim('fullSystemModel',3000);
            % Обрезаем вектор до 300 метров по высоте
            for i = 1:length(app.output(:,3))
                %if app.output(i,3) - str2double(get_param('BUP_FOR_GUI/ReliefScenario/ReliefOnTDPValue', 'Value')) <= app.touchdownAltitudeEdit.Value
                if app.output(i,3) <= 0
                    break;
                end
            end
            app.output = app.output(1:i,:);
            % Обозначим точку необходимого приземления
            app.X_TDP = [app.touchdownLongitudeEdit.Value - 0.0027, app.touchdownLongitudeEdit.Value + 0.0027]; % 0.01
            app.Y_TDP = [app.touchdownLatitudeEdit.Value - 0.0027, app.touchdownLatitudeEdit.Value + 0.0027]; % 0.01
            % Строим траекторию
            plot(app.plot2d, app.output(:,1), app.output(:,2))
            % И добавим точку необходимого пересечения
            hold(app.plot2d,'on')
            grid(app.plot2d,'on');
            zoom(app.plot2d,'on');
            plot(app.plot2d, app.X_TDP, [app.touchdownLatitudeEdit.Value, app.touchdownLatitudeEdit.Value], 'k')
            plot(app.plot2d, [app.touchdownLongitudeEdit.Value, app.touchdownLongitudeEdit.Value], app.Y_TDP, 'k')
            
            %% Окружности КВО запланированной точки приземления
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.plot2d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                             app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.plot2d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                             app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.plot2d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                             app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 450/1000*360/40000; % 450 м предел
            plot(app.plot2d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                             app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            %% Окружности КВО точки приземления полученной с радиостанции
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.plot2d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.plot2d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.plot2d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 450/1000*360/40000; % 450 м предел
            plot(app.plot2d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
      
            % Строим так же 3D график полета
            plot3(app.plot3d, app.output(:,1), app.output(:,2), app.output(:,3))
            hold(app.plot3d,'on')
            grid(app.plot3d,'on');
            plot3(app.plot3d, app.X_TDP, [app.touchdownLatitudeEdit.Value, app.touchdownLatitudeEdit.Value], [0, 0], 'k')
            plot3(app.plot3d, [app.touchdownLongitudeEdit.Value, app.touchdownLongitudeEdit.Value], app.Y_TDP,  [0, 0], 'k')
            rotate3d(app.plot3d, 'on'); 
            %% Окружности КВО запланированной точки приземления
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.plot3d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                              app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.plot3d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                              app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.plot3d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi),...
                              app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            R = 450/1000*360/40000; % 450 м предел
            plot(app.plot3d, app.touchdownLongitudeEdit.Value + R * cos(0:0.001:2*pi), ...
                              app.touchdownLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'k');
            %% Окружности КВО точки приземления полученной с радиостанции
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.plot3d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.plot3d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.plot3d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            R = 450/1000*360/40000; % 450 м предел
            plot(app.plot3d, app.radioPointLongitudeEdit.Value + R * cos(0:0.001:2*pi),... 
                             app.radioPointLatitudeEdit.Value + R * sin(0:0.001:2*pi), 'r');
            
        end

        % Button pushed function: restoreSessionButton
        function restoreSessionButtonPushed(app, event)
            % Восстанавливаем точку приземления из Simulink
            app.touchdownLatitudeEdit.Value = str2double( get_param('fullSystemModel/TDP_lat_y', 'Value'));
            app.touchdownLongitudeEdit.Value = str2double( get_param('fullSystemModel/TDP_lon_x', 'Value'));
            app.touchdownAltitudeEdit.Value = str2double( get_param('fullSystemModel/TDP_alt_z', 'Value'));
            
            % Восстанавливаем точку выброса из Simulink
            app.initialPointLatitudeEdit.Value = str2double( get_param('fullSystemModel/Initial_LAT', 'Value'));
            app.initialPointLongitudeEdit.Value = str2double( get_param('fullSystemModel/Initial_LON', 'Value'));
            app.initialPointAltitudeEdit.Value = str2double( get_param('fullSystemModel/Initial_ALT', 'Value'));
            
            % Восстанавливаем точку приземления от радиостанции
            app.radioPointLatitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.lat', 'Value'));
            app.radioPointLongitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.lon', 'Value'));
            app.radioPointAltitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.alt', 'Value'));
            app.sendtimeEdit.Value = ( get_param('fullSystemModel/Radiostation/sendTime', 'Time')); 
            
            % Восставливаем параметры объекта управления
            app.horizontalVelocityEdit.Value        = str2double( get_param('fullSystemModel/Plant/Vh1', 'Gain'));
            app.verticalVelocityEdit.Value        = str2double( get_param('fullSystemModel/Plant/Vv', 'Gain'));
            app.turningSpeedEdit.Value = 50 /(1/(str2double( get_param('fullSystemModel/Plant/AngleSpeed', 'Gain'))));
            
            % Восстанавливаем параметры базового регулятора
            app.strapLimit.Value = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Sat', 'UpperLimit'));
            app.sensitivity.Value      = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain'));
            app.sensitivity.Value      = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain'));
            app.boxSize.Value            = str2double( get_param('fullSystemModel/flightController/BoxSize', 'Value'));
            app.angle.Value        = str2double( get_param('fullSystemModel/flightController/AngleManevr', 'Value'));
            app.arrivalRadius.Value      = str2double( get_param('fullSystemModel/flightController/ArrivalRadius', 'Value'));
            
            % Восстанавливаем параметры переключателей
            app.touchdownSystemEnable.Value    = get_param('fullSystemModel/flightController/TD_SysSwitch', 'sw');
            app.internalCourseCalculatorEnable.Value  = get_param('fullSystemModel/flightController/DataAnalyzer/CourseSwitch', 'sw');
            %app.TD_Reg_Select.Value    = get_param('BUP_FOR_GUI/Easy_reg/Mode','Value');
            
            % Восстанавливаем параметры имитатора рельефа
            app.currentPointReliefEdit.Value    = str2double(get_param('fullSystemModel/ReliefScenario/ReliefValue', 'Value'));  
            app.currentReliefAvailableCheckbox.Value           = str2double(get_param('fullSystemModel/ReliefScenario/ReliefAvailableValue', 'Value'));
            app.touchdownPointReliefEdit.Value = str2double(get_param('fullSystemModel/ReliefScenario/ReliefOnTDPValue', 'Value'));
            app.autoReliefCheckbox.Value               = str2double(get_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw'));
            app.radioPointReliefEdit.Value    = str2double(get_param('fullSystemModel/ReliefScenario/ReliefOnRPValue', 'Value'));
            EnableDisableReliefPanel(app, app.autoReliefCheckbox.Value);


            % Восстанавливаем параметры имитатора ветра
            %set_param('BUP_FOR_GUI/WindSpeed', 'Value',  num2str(app.windSpeedSlider.Value));  
            %set_param('BUP_FOR_GUI/WindAngle', 'Value',  num2str(app.windAngleKnob.Value)); 
            app.autoWindCheckbox.Value  = str2double(get_param('fullSystemModel/WindScenario/AutoModeS', 'sw'));
            EnableDisableWindPanel(app, app.autoWindCheckbox.Value);
            
            % Восстанавливаем режим регулятора на радиокнопки
            temp = str2double(get_param('fullSystemModel/flightController/Mode','Value'));
            if temp == 0
                disp("= 0")
                app.onlyTargetingModeButton.Value = 1;
            elseif temp == 1
                disp("= 1")
                app.boxModeButton.Value = 1;
            elseif temp == 2
                disp("= 2")
                 app.progressiveModeButton.Value = 1;
            end
   
            % Разблокируем кнопку запуска
            app.runButton.Enable = 'on';
            
        end

        % Value changed function: TD_Reg_Select
        function TD_Reg_SelectValueChanged(app, event)
            if app.TD_Reg_Select.Value == '0'
                % Заблокируем поля посадочного регулятора
                app.boxSize.Enable = 'off';
                app.angle.Enable = 'off';
                app.arrivalRadius.Enable = 'off';
            else
                % Заблокируем поля посадочного регулятора
                % app.TD_Reg_ParamPanel.Visible = 'on';
                app.boxSize.Enable = 'on';
                app.angle.Enable = 'on';
                app.arrivalRadius.Enable = 'on';
            end
        end

        % Value changed function: autoWindCheckbox
        function autoWindCheckboxValueChanged(app, event)
            value = app.autoWindCheckbox.Value;
            EnableDisableWindPanel(app, value);
        end

        % Value changed function: autoReliefCheckbox
        function autoReliefCheckboxValueChanged(app, event)
            value = app.autoReliefCheckbox.Value;
            EnableDisableReliefPanel(app, value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BupRegulatorTunerUIFigure = uifigure;
            app.BupRegulatorTunerUIFigure.Name = 'Bup Regulator Tuner';
            app.touchdownPointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.touchdownPointPanel.Title = 'Touchdown point';
            app.LatitudedegLabel = uilabel(app.touchdownPointPanel);
            app.LatitudedegLabel.Text = 'Latitude, deg';
            app.touchdownLatitudeEdit = uieditfield(app.touchdownPointPanel, 'numeric');
            app.touchdownLatitudeEdit.ValueDisplayFormat = '%.6f';
            app.touchdownLatitudeEdit.Value = 56.86825;
            app.LongitudedegLabel = uilabel(app.touchdownPointPanel);
            app.LongitudedegLabel.Text = 'Longitude, deg';
            app.touchdownLongitudeEdit = uieditfield(app.touchdownPointPanel, 'numeric');
            app.touchdownLongitudeEdit.ValueDisplayFormat = '%.6f';
            app.touchdownLongitudeEdit.Value = 41.51329;
            app.AltitudemLabel = uilabel(app.touchdownPointPanel);
            app.AltitudemLabel.Text = 'Altitude, m';
            app.touchdownAltitudeEdit = uieditfield(app.touchdownPointPanel, 'numeric');
            app.touchdownAltitudeEdit.ValueDisplayFormat = '%.3f';
            app.initialPointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.initialPointPanel.Title = 'Initial point';
            app.LatitudedegLabel_2 = uilabel(app.initialPointPanel);
            app.LatitudedegLabel_2.Text = 'Latitude, deg';
            app.initialPointLatitudeEdit = uieditfield(app.initialPointPanel, 'numeric');
            app.initialPointLatitudeEdit.Limits = [-90 90];
            app.initialPointLatitudeEdit.ValueDisplayFormat = '%.6f';
            app.initialPointLatitudeEdit.Value = 56.86825;
            app.LongitudedegLabel_2 = uilabel(app.initialPointPanel);
            app.LongitudedegLabel_2.Text = 'Longitude, deg';
            app.initialPointLongitudeEdit = uieditfield(app.initialPointPanel, 'numeric');
            app.initialPointLongitudeEdit.ValueDisplayFormat = '%.6f';
            app.initialPointLongitudeEdit.Value = 41.48775;
            app.AltitudemLabel_2 = uilabel(app.initialPointPanel);
            app.AltitudemLabel_2.Text = 'Altitude, m';
            app.initialPointAltitudeEdit = uieditfield(app.initialPointPanel, 'numeric');
            app.initialPointAltitudeEdit.ValueDisplayFormat = '%.3f';
            app.initialPointAltitudeEdit.Value = 16000;
            app.windControlPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.windControlPanel.Title = 'Wind control';
            app.WindAngleKnobLabel = uilabel(app.windControlPanel);
            app.WindAngleKnobLabel.Text = 'Wind Angle';
            app.windAngleKnob = uiknob(app.windControlPanel, 'continuous');
            app.windAngleKnob.Limits = [-180 180];
            app.SpeedSliderLabel = uilabel(app.windControlPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.windSpeedSlider = uislider(app.windControlPanel);
            app.windSpeedSlider.Limits = [0 15];
            app.autoWindCheckbox = uicheckbox(app.windControlPanel);
            app.autoWindCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoWindCheckboxValueChanged, true);
            app.autoWindCheckbox.Text = 'Auto';
            app.regulatorSettingsPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.regulatorSettingsPanel.Title = 'Base Regulator''s Parameters';
            app.StapLimitLabel = uilabel(app.regulatorSettingsPanel);
            app.StapLimitLabel.Text = 'Stap Limit, %';
            app.strapLimit = uieditfield(app.regulatorSettingsPanel, 'numeric');
            app.strapLimit.Value = 50;
            app.GainSensitivityLabel = uilabel(app.regulatorSettingsPanel);
            app.GainSensitivityLabel.Text = 'Gain Sensitivity';
            app.sensitivity = uieditfield(app.regulatorSettingsPanel, 'numeric');
            app.sensitivity.Value = 50;
            app.BoxSizemLabel = uilabel(app.regulatorSettingsPanel);
            app.BoxSizemLabel.Text = 'Box Size, m';
            app.boxSize = uieditfield(app.regulatorSettingsPanel, 'numeric');
            app.boxSize.Value = 0.01;
            app.AngleStepdegLabel = uilabel(app.regulatorSettingsPanel);
            app.AngleStepdegLabel.Text = 'AngleStep, deg';
            app.angle = uieditfield(app.regulatorSettingsPanel, 'numeric');
            app.angle.Value = 30;
            app.ArrivalRadiusmLabel = uilabel(app.regulatorSettingsPanel);
            app.ArrivalRadiusmLabel.Text = 'Arrival Radius, m';
            app.arrivalRadius = uieditfield(app.regulatorSettingsPanel, 'numeric');
            app.arrivalRadius.Value = 200;
            app.runButton = uibutton(app.BupRegulatorTunerUIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Enable = 'off';
            app.runButton.Text = 'Run';
            app.tabs = uitabgroup(app.BupRegulatorTunerUIFigure);
            app.plot2dTab = uitab(app.tabs);
            app.plot2dTab.Title = '2d';
            app.plot2d = uiaxes(app.plot2dTab);
            title(app.plot2d, '2D Trace')
            xlabel(app.plot2d, 'Lon')
            ylabel(app.plot2d, 'Lat')
            app.plot3dTab = uitab(app.tabs);
            app.plot3dTab.Title = '3D';
            app.plot3d = uiaxes(app.plot3dTab);
            title(app.plot3d, '3D Trace')
            xlabel(app.plot3d, 'X')
            ylabel(app.plot3d, 'Y')
            app.plantParametersPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.plantParametersPanel.Title = 'Plant Parameters';
            app.HorspeedVhmsEditFieldLabel = uilabel(app.plantParametersPanel);
            app.HorspeedVhmsEditFieldLabel.Text = 'Hor speed Vh, m/s';
            app.horizontalVelocityEdit = uieditfield(app.plantParametersPanel, 'numeric');
            app.horizontalVelocityEdit.Value = 10;
            app.VerspeedVvmsEditFieldLabel = uilabel(app.plantParametersPanel);
            app.VerspeedVvmsEditFieldLabel.Text = 'Ver speed Vv, m/s';
            app.verticalVelocityEdit = uieditfield(app.plantParametersPanel, 'numeric');
            app.verticalVelocityEdit.Value = 8.3;
            app.Turnspeedat50degsLabel = uilabel(app.plantParametersPanel);
            app.Turnspeedat50degsLabel.Text = 'Turn speed at 50%, deg/s';
            app.turningSpeedEdit = uieditfield(app.plantParametersPanel, 'numeric');
            app.turningSpeedEdit.Value = 3;
            app.restoreSessionButton = uibutton(app.BupRegulatorTunerUIFigure, 'push');
            app.restoreSessionButton.ButtonPushedFcn = createCallbackFcn(app, @restoreSessionButtonPushed, true);
            app.restoreSessionButton.Text = 'Load Last Parameters';
            app.regulatorSwithPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.regulatorSwithPanel.Title = 'Regulator''s Switch';
            app.TouchdownCommandSwitchLabel = uilabel(app.regulatorSwithPanel);
            app.TouchdownCommandSwitchLabel.Text = 'Touchdown Command';
            app.touchdownSystemEnable = uiswitch(app.regulatorSwithPanel, 'slider');
            app.touchdownSystemEnable.ItemsData = {'0', '1'};
            app.touchdownSystemEnable.Value = '0';
            app.TouchdownRegulatorSwitchLabel = uilabel(app.regulatorSwithPanel);
            app.TouchdownRegulatorSwitchLabel.Enable = 'off';
            app.TouchdownRegulatorSwitchLabel.Text = 'Touchdown Regulator';
            app.TD_Reg_Select = uiswitch(app.regulatorSwithPanel, 'slider');
            app.TD_Reg_Select.ItemsData = {'0', '1'};
            app.TD_Reg_Select.ValueChangedFcn = createCallbackFcn(app, @TD_Reg_SelectValueChanged, true);
            app.TD_Reg_Select.Enable = 'off';
            app.TD_Reg_Select.Value = '0';
            app.InternalcoursecalculatorSwitchLabel = uilabel(app.regulatorSwithPanel);
            app.InternalcoursecalculatorSwitchLabel.Text = 'Internal course calculator';
            app.internalCourseCalculatorEnable = uiswitch(app.regulatorSwithPanel, 'slider');
            app.internalCourseCalculatorEnable.ItemsData = {'0', '1'};
            app.internalCourseCalculatorEnable.Value = '1';
            app.regulatorModePanel = uibuttongroup(app.BupRegulatorTunerUIFigure);
            app.regulatorModePanel.Title = 'RegulatorMode';
            app.onlyTargetingModeButton = uiradiobutton(app.regulatorModePanel);
            app.onlyTargetingModeButton.Text = 'Only TDP';
            app.boxModeButton = uiradiobutton(app.regulatorModePanel);
            app.boxModeButton.Text = 'Box';
            app.progressiveModeButton = uiradiobutton(app.regulatorModePanel);
            app.progressiveModeButton.Text = 'Angle & Box';
            app.progressiveModeButton.Value = true;
            app.reliefPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.reliefPanel.Title = 'Relief Settings';
            app.autoReliefCheckbox = uicheckbox(app.reliefPanel);
            app.autoReliefCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoReliefCheckboxValueChanged, true);
            app.autoReliefCheckbox.Text = 'Auto';
            app.CurrentpointreliefmLabel = uilabel(app.reliefPanel);
            app.CurrentpointreliefmLabel.Text = 'Current point relief, m';
            app.currentPointReliefEdit = uieditfield(app.reliefPanel, 'numeric');
            app.TouchdownpointreliefmLabel = uilabel(app.reliefPanel);
            app.TouchdownpointreliefmLabel.Text = 'Touchdown point relief, m';
            app.touchdownPointReliefEdit = uieditfield(app.reliefPanel, 'numeric');
            app.currentReliefAvailableCheckbox = uicheckbox(app.reliefPanel);
            app.currentReliefAvailableCheckbox.Text = 'Const Available';
            app.RadiopointreliefmEditFieldLabel = uilabel(app.reliefPanel);
            app.RadiopointreliefmEditFieldLabel.Text = 'Radio point relief, m';
            app.radioPointReliefEdit = uieditfield(app.reliefPanel, 'numeric');
            app.radioPointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.radioPointPanel.Title = 'Radio point';
            app.LatitudedegLabel_3 = uilabel(app.radioPointPanel);
            app.LatitudedegLabel_3.Text = 'Latitude, deg';
            app.radioPointLatitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointLatitudeEdit.Limits = [-90 90];
            app.radioPointLatitudeEdit.ValueDisplayFormat = '%.6f';
            app.radioPointLatitudeEdit.Value = 56.86825;
            app.LongitudedegLabel_3 = uilabel(app.radioPointPanel);
            app.LongitudedegLabel_3.Text = 'Longitude, deg';
            app.radioPointLongitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointLongitudeEdit.ValueDisplayFormat = '%.6f';
            app.radioPointLongitudeEdit.Value = 41.48775;
            app.AltitudemLabel_3 = uilabel(app.radioPointPanel);
            app.AltitudemLabel_3.Text = 'Altitude, m';
            app.radioPointAltitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointAltitudeEdit.ValueDisplayFormat = '%.3f';
            app.radioPointAltitudeEdit.Value = 16000;
            app.SendtimesecLabel = uilabel(app.radioPointPanel);
            app.SendtimesecLabel.Text = 'Send time, sec';
            app.sendtimeEdit = uieditfield(app.radioPointPanel, 'text');
            app.sendtimeEdit.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ControllerTuner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BupRegulatorTunerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BupRegulatorTunerUIFigure)
        end
    end
end