classdef VectorCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ShowHidePanel               matlab.ui.container.Panel
        AirSpeed1CheckBox           matlab.ui.control.CheckBox
        WindSpeed1CheckBox          matlab.ui.control.CheckBox
        GroundSpeed1CheckBox        matlab.ui.control.CheckBox
        Circle1CheckBox             matlab.ui.control.CheckBox
        AirSpeed2CheckBox           matlab.ui.control.CheckBox
        WindSpeed2CheckBox          matlab.ui.control.CheckBox
        GroundSpeed2CheckBox        matlab.ui.control.CheckBox
        GroundSpeed2innullCheckBox  matlab.ui.control.CheckBox
        Circle2CheckBox             matlab.ui.control.CheckBox
        AirSpeedPanel               matlab.ui.container.Panel
        HeadingKnobLabel            matlab.ui.control.Label
        HeadingKnob                 matlab.ui.control.Knob
        AirSpeedEditFieldLabel      matlab.ui.control.Label
        AirSpeed                    matlab.ui.control.NumericEditField
        WindPanel                   matlab.ui.container.Panel
        WindAngleKnobLabel          matlab.ui.control.Label
        WindAngleKnob               matlab.ui.control.Knob
        WindForceEditFieldLabel     matlab.ui.control.Label
        WindForce                   matlab.ui.control.NumericEditField
        RotationPanel               matlab.ui.container.Panel
        RotationAngleKnobLabel      matlab.ui.control.Label
        RotationAngleKnob           matlab.ui.control.Knob
        PlotPanel                   matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Именованные графики
        AirSpeed1
        WindSpeed1
        GroundSpeed1
        Circle1
        AirSpeed2
        WindSpeed2
        GroundSpeed2
        GroundSpeed2_NN
        Circle2
        estimatedWind
        % Хранимые переменные 
        AirSpeedX         % 
        AirSpeedY         %
        WindX             %
        WindY             %
        GroundSpeedX      %
        GroundSpeedY      %
        RotationAirX      %
        RotationAirY      %
        RotationGroundX   %
        RotationGroundY   %
        % Данные с интерфейса
        airSpeed
        windForce
        windAngle
        heading
        rotation
        % Координаты точек пересечения окружностей 
        P1x
        P1y
        P2x
        P2y
    end

    methods (Access = private)
    
        function paintVectors(app)
            % Очищаем plot
            cla(app.UIAxes,'reset');
            % Обновляем данные по основным векторам
            app.updateData();
            % Находим решение по точкам пересения
            app.WindCalc();
            
            % Начинаем отрисовывать данные--------------------------------------
            
            app.plotAirSpeed1();
            app.plotWindSpeed1();
            app.plotGroundSpeed1();
            app.plotCircle1();
            app.plotAirSpeed2();
            app.plotWindSpeed2();
            app.plotGroundSpeed2NN();
            app.plotGroundSpeed2();
            app.plotCircle2();
            
            grid(app.UIAxes, "on");
            % Пределы графика
%             rightLimX = 2.5*app.airSpeed;
%             leftLimX = - rightLimX;
%             rightLimY = 2.2*app.airSpeed;
%             leftLimY = - rightLimY;
%             xlim(app.UIAxes, [leftLimX rightLimX]);
%             ylim(app.UIAxes, [leftLimY rightLimY]);


        end
        
        function plotAirSpeed1(app)
            value = app.AirSpeed1CheckBox.Value;
            if value == 0
               delete(app.AirSpeed1);
            else
                hold(app.UIAxes, "on");
                app.AirSpeed1 = plot(app.UIAxes, [0 app.AirSpeedX], [0 app.AirSpeedY], 'r--');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotWindSpeed1(app)
            value = app.WindSpeed1CheckBox.Value;
            if value == 0
               delete(app.WindSpeed1);
            else
                hold(app.UIAxes, "on");
                app.WindSpeed1 = plot(app.UIAxes, [app.AirSpeedX app.WindX+app.AirSpeedX], [app.AirSpeedY app.WindY+app.AirSpeedY], 'c--');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotGroundSpeed1(app)
            value = app.GroundSpeed1CheckBox.Value;
            if value == 0
               delete(app.GroundSpeed1);
            else
                hold(app.UIAxes, "on");
                app.GroundSpeed1 = plot(app.UIAxes, [0 app.GroundSpeedX], [0 app.GroundSpeedY], 'b');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotCircle1(app)
            value = app.Circle1CheckBox.Value;
            if value == 0
               delete(app.Circle1);
            else
                hold(app.UIAxes, "on");
                app.Circle1 = plot(app.UIAxes, app.GroundSpeedX + app.airSpeed * cos(0:0.001:2*pi), app.GroundSpeedY + app.airSpeed * sin(0:0.001:2*pi), 'k--');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotAirSpeed2(app)
            value = app.AirSpeed2CheckBox.Value;
            if value == 0
               delete(app.AirSpeed2);
            else
                hold(app.UIAxes, "on");
                app.AirSpeed2 = plot(app.UIAxes, [app.GroundSpeedX app.GroundSpeedX+app.RotationAirX], [app.GroundSpeedY app.GroundSpeedY+app.RotationAirY], 'r--');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotWindSpeed2(app)
            value = app.WindSpeed2CheckBox.Value;
            if value == 0
               delete(app.WindSpeed2);
            else
                hold(app.UIAxes, "on");
                app.WindSpeed2 = plot(app.UIAxes, [app.GroundSpeedX+app.RotationAirX app.GroundSpeedX+app.RotationAirX+app.WindX], [app.GroundSpeedY+app.RotationAirY app.GroundSpeedY+app.RotationAirY+app.WindY], 'c--');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotGroundSpeed2NN(app)
            value = app.GroundSpeed2innullCheckBox.Value;
            if value == 0
               delete(app.GroundSpeed2_NN);
            else
                hold(app.UIAxes, "on");
                app.GroundSpeed2_NN = plot(app.UIAxes, [0 app.RotationGroundX], [0 app.RotationGroundY], 'b');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotGroundSpeed2(app)
            value = app.GroundSpeed2CheckBox.Value;
            if value == 0
               delete(app.GroundSpeed2);
            else
                hold(app.UIAxes, "on");
                app.GroundSpeed2 = plot(app.UIAxes, [app.GroundSpeedX app.GroundSpeedX+app.RotationGroundX], [app.GroundSpeedY app.GroundSpeedY+app.RotationGroundY], 'b');
                hold(app.UIAxes, "off");
            end
        end
        
        function plotCircle2(app)
             value = app.Circle2CheckBox.Value;
            if value == 0
               delete(app.Circle2);
            else
                hold(app.UIAxes, "on");
                app.Circle2 = plot(app.UIAxes, app.RotationGroundX + app.airSpeed * cos(0:0.001:2*pi), app.RotationGroundY + app.airSpeed * sin(0:0.001:2*pi), 'k--');
                hold(app.UIAxes, "off");
            end
        end
        
        function updateData(app)
            app.airSpeed = app.AirSpeed.Value;
            app.windForce = app.WindForce.Value;
            app.windAngle = app.WindAngleKnob.Value;
            app.heading = app.HeadingKnob.Value;
            app.rotation = app.RotationAngleKnob.Value;
            % Разложение вектора воздушной скорости
            app.AirSpeedX = app.airSpeed * cos(pi/2 - app.heading/180*pi);
            app.AirSpeedY = app.airSpeed * cos(- app.heading/180*pi);
            % Разложение вектора ветра
            app.WindX = app.windForce * cos(pi/2 - app.windAngle/180*pi);
            app.WindY = app.windForce * cos(- app.windAngle/180*pi);
            % Разложение вектора путевой скорости
            app.GroundSpeedX = app.AirSpeedX + app.WindX;
            app.GroundSpeedY = app.AirSpeedY + app.WindY;
            % Определяем вектор воздушной скорости после поворота
            app.RotationAirX = app.airSpeed * cos(pi/2 - (app.heading+app.rotation)/180*pi);
            app.RotationAirY = app.airSpeed * cos(- (app.heading+app.rotation)/180*pi);
            % Определяем вектор пути после поворота
            app.RotationGroundX = app.RotationAirX + app.WindX;
            app.RotationGroundY = app.RotationAirY + app.WindY;
        end 
        
        function results = WindCalc(app)
            groundSpeed1 = sqrt(app.GroundSpeedX^2 + app.GroundSpeedY^2);
            course1 = Heading([app.GroundSpeedY app.GroundSpeedX], [0 0], 0);
 
            groundSpeed2 = sqrt(app.RotationGroundX^2 + app.RotationGroundY^2);
            course2 = Heading([app.RotationGroundY app.RotationGroundX], [0 0], 0);
            
            [windForceEst, windAngleEst] = WindEstimator(groundSpeed1, course1, groundSpeed2, course2, app.airSpeed)
            app.WindX = windForceEst * cos(pi/2 - windAngleEst);
            app.WindY = windForceEst * cos(- windAngleEst);
            
            hold(app.UIAxes, "on");
            app.estimatedWind = plot(app.UIAxes, [0 app.WindX], [0 app.WindY], 'Linewidth',3, 'Color','r');
            hold(app.UIAxes, "off");
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.paintVectors();
        end

        % Callback function: HeadingKnob
        function HeadingKnobCallback(app, event)
            app.heading = app.HeadingKnob.Value;
            app.paintVectors();
        end

        % Callback function: WindAngleKnob
        function WindAngleKnobCallback(app, event)
            app.windAngle = app.WindAngleKnob.Value;
            app.paintVectors();
        end

        % Value changed function: AirSpeed
        function AirSpeedCallback(app, event)
            app.airSpeed = app.AirSpeed.Value;
            app.paintVectors();
        end

        % Value changed function: WindForce
        function WindForceCallback(app, event)
            app.windForce = app.WindForce.Value;
            app.paintVectors();
        end

        % Callback function: RotationAngleKnob
        function RotationAngleKnobCallback(app, event)
            app.rotation = app.RotationAngleKnob.Value;
            app.paintVectors();
        end

        % Value changed function: AirSpeed1CheckBox
        function AirSpeed1CheckBoxCallback(app, event)
            app.plotAirSpeed1();
        end

        % Value changed function: WindSpeed1CheckBox
        function WindSpeed1CheckBoxCallback(app, event)
            app.plotWindSpeed1();
        end

        % Value changed function: GroundSpeed1CheckBox
        function GroundSpeed1CheckBoxCallback(app, event)
            app.plotGroundSpeed1();
        end

        % Value changed function: Circle1CheckBox
        function Circle1CheckBoxCallback(app, event)
            app.plotCircle1();
        end

        % Value changed function: AirSpeed2CheckBox
        function AirSpeed2CheckBoxCallback(app, event)
            app.plotAirSpeed2();
        end

        % Value changed function: WindSpeed2CheckBox
        function WindSpeed2CheckBoxCallback(app, event)
            app.plotWindSpeed2();
        end

        % Value changed function: GroundSpeed2CheckBox
        function GroundSpeed2CheckBoxCallback(app, event)
            app.plotGroundSpeed2();
        end

        % Value changed function: GroundSpeed2innullCheckBox
        function GroundSpeed2innullCheckBoxCallback(app, event)
            app.plotGroundSpeed2NN();
        end

        % Value changed function: Circle2CheckBox
        function Circle2CheckBoxCallback(app, event)
            app.plotCircle2();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ShowHidePanel = uipanel(app.UIFigure);
            app.ShowHidePanel.Title = 'Show/Hide';
            app.AirSpeed1CheckBox = uicheckbox(app.ShowHidePanel);
            app.AirSpeed1CheckBox.ValueChangedFcn = createCallbackFcn(app, @AirSpeed1CheckBoxCallback, true);
            app.AirSpeed1CheckBox.Text = 'AirSpeed1';
            app.AirSpeed1CheckBox.Value = true;
            app.WindSpeed1CheckBox = uicheckbox(app.ShowHidePanel);
            app.WindSpeed1CheckBox.ValueChangedFcn = createCallbackFcn(app, @WindSpeed1CheckBoxCallback, true);
            app.WindSpeed1CheckBox.Text = 'WindSpeed1';
            app.WindSpeed1CheckBox.Value = true;
            app.GroundSpeed1CheckBox = uicheckbox(app.ShowHidePanel);
            app.GroundSpeed1CheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundSpeed1CheckBoxCallback, true);
            app.GroundSpeed1CheckBox.Text = 'GroundSpeed1';
            app.GroundSpeed1CheckBox.Value = true;
            app.Circle1CheckBox = uicheckbox(app.ShowHidePanel);
            app.Circle1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Circle1CheckBoxCallback, true);
            app.Circle1CheckBox.Text = 'Circle1';
            app.Circle1CheckBox.Value = true;
            app.AirSpeed2CheckBox = uicheckbox(app.ShowHidePanel);
            app.AirSpeed2CheckBox.ValueChangedFcn = createCallbackFcn(app, @AirSpeed2CheckBoxCallback, true);
            app.AirSpeed2CheckBox.Text = 'AirSpeed2';
            app.AirSpeed2CheckBox.Value = true;
            app.WindSpeed2CheckBox = uicheckbox(app.ShowHidePanel);
            app.WindSpeed2CheckBox.ValueChangedFcn = createCallbackFcn(app, @WindSpeed2CheckBoxCallback, true);
            app.WindSpeed2CheckBox.Text = 'WindSpeed2';
            app.WindSpeed2CheckBox.Value = true;
            app.GroundSpeed2CheckBox = uicheckbox(app.ShowHidePanel);
            app.GroundSpeed2CheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundSpeed2CheckBoxCallback, true);
            app.GroundSpeed2CheckBox.Text = 'GroundSpeed2';
            app.GroundSpeed2CheckBox.Value = true;
            app.GroundSpeed2innullCheckBox = uicheckbox(app.ShowHidePanel);
            app.GroundSpeed2innullCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundSpeed2innullCheckBoxCallback, true);
            app.GroundSpeed2innullCheckBox.Text = 'GroundSpeed2 in null';
            app.GroundSpeed2innullCheckBox.Value = true;
            app.Circle2CheckBox = uicheckbox(app.ShowHidePanel);
            app.Circle2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Circle2CheckBoxCallback, true);
            app.Circle2CheckBox.Text = 'Circle2';
            app.Circle2CheckBox.Value = true;
            app.AirSpeedPanel = uipanel(app.UIFigure);
            app.AirSpeedPanel.Title = 'Air Speed';
            app.HeadingKnobLabel = uilabel(app.AirSpeedPanel);
            app.HeadingKnobLabel.Text = 'Heading';
            app.HeadingKnob = uiknob(app.AirSpeedPanel, 'continuous');
            app.HeadingKnob.Limits = [-180 180];
            app.HeadingKnob.ValueChangedFcn = createCallbackFcn(app, @HeadingKnobCallback, true);
            app.HeadingKnob.ValueChangingFcn = createCallbackFcn(app, @HeadingKnobCallback, true);
            app.AirSpeedEditFieldLabel = uilabel(app.AirSpeedPanel);
            app.AirSpeedEditFieldLabel.Text = 'AirSpeed';
            app.AirSpeed = uieditfield(app.AirSpeedPanel, 'numeric');
            app.AirSpeed.ValueChangedFcn = createCallbackFcn(app, @AirSpeedCallback, true);
            app.AirSpeed.Value = 20;
            app.WindPanel = uipanel(app.UIFigure);
            app.WindPanel.Title = 'Wind';
            app.WindAngleKnobLabel = uilabel(app.WindPanel);
            app.WindAngleKnobLabel.Text = 'WindAngle';
            app.WindAngleKnob = uiknob(app.WindPanel, 'continuous');
            app.WindAngleKnob.Limits = [-180 180];
            app.WindAngleKnob.ValueChangedFcn = createCallbackFcn(app, @WindAngleKnobCallback, true);
            app.WindAngleKnob.ValueChangingFcn = createCallbackFcn(app, @WindAngleKnobCallback, true);
            app.WindForceEditFieldLabel = uilabel(app.WindPanel);
            app.WindForceEditFieldLabel.Text = 'WindForce';
            app.WindForce = uieditfield(app.WindPanel, 'numeric');
            app.WindForce.ValueChangedFcn = createCallbackFcn(app, @WindForceCallback, true);
            app.WindForce.Value = 5;
            app.RotationPanel = uipanel(app.UIFigure);
            app.RotationPanel.Title = 'Rotation';
            app.RotationAngleKnobLabel = uilabel(app.RotationPanel);
            app.RotationAngleKnobLabel.Text = 'RotationAngle';
            app.RotationAngleKnob = uiknob(app.RotationPanel, 'continuous');
            app.RotationAngleKnob.Limits = [-90 90];
            app.RotationAngleKnob.ValueChangedFcn = createCallbackFcn(app, @RotationAngleKnobCallback, true);
            app.RotationAngleKnob.ValueChangingFcn = createCallbackFcn(app, @RotationAngleKnobCallback, true);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = VectorCreator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end